classdef SOLINDAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        SOLINDARPanel     matlab.ui.container.Panel
        SonarButton       matlab.ui.control.StateButton
        LidarButton       matlab.ui.control.StateButton
        FusionButton      matlab.ui.control.StateButton
        PositionXLabel    matlab.ui.control.Label
        PositionYLabel    matlab.ui.control.Label
        ONSwitch          matlab.ui.control.Switch
        LogSwitch         matlab.ui.control.Switch
        LoggingLamp       matlab.ui.control.Lamp
        LoggingLabel      matlab.ui.control.Label
        PositionXNumber   matlab.ui.control.Label
        PositionYNumber   matlab.ui.control.Label
        DistanciaLabel    matlab.ui.control.Label
        DistanciacmLabel  matlab.ui.control.Label
        AnguloLabel       matlab.ui.control.Label
        AngulogrdsLabel   matlab.ui.control.Label
        PositionMotor     matlab.ui.control.Gauge
    end

    
    properties (Access = private)
         % Serial
         s = serial('COM8')
         baudrate = 9600;
         buffersize = 4096;
         % General parameters
         window = 1008;
         fs = 2800;
         posicion
         sonar
         lidar
         fusion
    end   
    
    methods (Access = private)
        % Callback para cuando se genera el evento de buffer lleno
        function readSerial(app)
            data = fread(app.s, app.buffersize, 'int8');
            [posicion_dat, sonar_dat, lidar_dat, blocksize] = unpack(app,data);
            
            % Plot
            addpoints(app.sonar, 1:blocksize, sonar_dat);
            addpoints(app.lidar, 1:blocksize, lidar_dat);
            addpoints(app.fusion, 1:blocksize, lidar_dat);
        end
    
        function initSerial(app)
            app.s.BaudRate = app.baudrate;
            app.s.InputBufferSize = app.buffersize;
            app.s.BytesAvailableFcnCount = app.buffersize;      % Número de bytes en el buffer de entrada para generar evento
            app.s.BytesAvailableFcnMode = 'byte';
            app.s.BytesAvailableFcn = @(~,~)app.readSerial; 
        end
        
        function initPolarscatter(app)
            % Create animatedlines filtroects
            
            app.sonar = animatedline(app.UIAxes, 'Color',[0.00,0.45,0.74]);         
            %app.lidar = polarscatter(app.UIAxes, 'Color',[0.85,0.33,0.10]);
            %app.fusion = polarscatter(app.UIAxes, 'Color',[0.93,0.69,0.13]);
            
            % Turn off the visibility in digitals channels by default
            app.sonar.Visible = 'off';
            app.lidar.Visible = 'off';
            app.fusion.Visible = 'off';
            app.LoggingLamp.Enable = 'off';
          
            % Assign maximum numbers of point
            app.sonar.MaximumNumPoints = app.window;
            app.lidar.MaximumNumPoints = app.window;
            app.fusion.MaximumNumPoints = app.window;
        end
    
    function  [posicion, sonar, lidar, blocksize] = unpack(app,data)
            databyte = int8(data);

            % Sync the data
            start = find(databyte > 0, 1);
            syncData = databyte(start+1:end-mod((app.buffersize-start),2));
            
            % Convert to unsigned
            syncData = typecast(syncData, 'uint8');
            
            % Resulting data block size
            blocksize = length(syncData)/4;
            
            byte0 = syncData(1:4:end);
            byte1 = syncData(2:4:end);
            byte2 = syncData(3:4:end);
            byte3 = syncData(4:4:end);
                        
            % Mask for analog channels
            Mask_posicion(1:blocksize,1) = uint8(63);
            Mask_sonar_l(1:blocksize,1) = uint8(127);
            Mask_sonar_h(1:blocksize,1) = uint8(96);
            Mask_lidar_l(1:blocksize,1) = uint8(31);
            Mask_lidar_h(1:blocksize,1) = uint8(127);
            
            posicion_d = bitand(byte0, Mask_posicion);
            sonar_l = bitand(byte1, Mask_sonar_l);
            sonar_h = bitand(byte2, Mask_sonar_h);
            lidar_l = bitand(byte2, Mask_lidar_l);
            lidar_h = bitand(byte3, Mask_lidar_h);
            
            % Sensors
            posicion = uint16(posicion_d);
            sonar = uint16(sonar_h)*64 + uint16(sonar_l);
            lidar = uint16(lidar_h)*64 + uint16(lidar_l);         
    end   
        
        function [posicionx,posiciony] = transformar(app,angulo)
            %Change polar values to rectangular values 
            
            posicionx = app.posicion.*cos(angulo);
            posiciony = app.posicion.*sin(angulo);  
            
        end
 end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initSerial;
            app.initPolarscatter;
            
            fopen(app.s);    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.s);
            delete(app);
        end

        % Value changed function: SonarButton
        function SonarButtonValueChanged(app, event)
            value = app.SonarButton.Value;
            app.sonar.Visible = value;
        end

        % Value changed function: LidarButton
        function LidarButtonValueChanged(app, event)
            value = app.LidarButton.Value;
            app.lidar.Visible = value;
        end

        % Value changed function: FusionButton
        function FusionButtonValueChanged(app, event)
            value = app.FusionButton.Value;
            app.fusion.Visible = value;
        end

        % Value changed function: LogSwitch
        function LogSwitchValueChanged(app, event)
            value = app.LogSwitch.Value;
            app.LoggingLamp.Enable = value;
            
            respuesta = strcmp(value,'on');
            if respuesta == 1
               fid = fopen('results.txt', 'wt');
               
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [1064 2064];
            app.UIAxes.YLim = [0 255];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [1032 1132 1232 1332 1432 1532 1632 1732 1832 1932 2032];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 25.5 51 76.5 102 127.5 153 178.5 204 229.5 255];
            app.UIAxes.YTickLabel = '';
            app.SOLINDARPanel = uipanel(app.UIFigure);
            app.SOLINDARPanel.Title = 'SOLINDAR';
            app.SonarButton = uibutton(app.SOLINDARPanel, 'state');
            app.SonarButton.ValueChangedFcn = createCallbackFcn(app, @SonarButtonValueChanged, true);
            app.SonarButton.Text = 'SONAR';
            app.SonarButton.Value = true;
            app.LidarButton = uibutton(app.SOLINDARPanel, 'state');
            app.LidarButton.ValueChangedFcn = createCallbackFcn(app, @LidarButtonValueChanged, true);
            app.LidarButton.Text = 'LIDAR';
            app.LidarButton.Value = true;
            app.FusionButton = uibutton(app.SOLINDARPanel, 'state');
            app.FusionButton.ValueChangedFcn = createCallbackFcn(app, @FusionButtonValueChanged, true);
            app.FusionButton.Text = 'Fusión';
            app.FusionButton.Value = true;
            app.PositionXLabel = uilabel(app.SOLINDARPanel);
            app.PositionXLabel.Text = 'Posición X: ';
            app.PositionYLabel = uilabel(app.SOLINDARPanel);
            app.PositionYLabel.Text = 'Posición Y: ';
            app.ONSwitch = uiswitch(app.SOLINDARPanel, 'slider');
            app.LogSwitch = uiswitch(app.SOLINDARPanel, 'slider');
            app.LogSwitch.ItemsData = {'Off', 'On'};
            app.LogSwitch.ValueChangedFcn = createCallbackFcn(app, @LogSwitchValueChanged, true);
            app.LoggingLamp = uilamp(app.SOLINDARPanel);
            app.LoggingLamp.Enable = 'off';
            app.LoggingLabel = uilabel(app.SOLINDARPanel);
            app.LoggingLabel.Text = 'Logging';
            app.PositionXNumber = uilabel(app.SOLINDARPanel);
            app.PositionXNumber.Text = '0';
            app.PositionYNumber = uilabel(app.SOLINDARPanel);
            app.PositionYNumber.Text = '0';
            app.DistanciaLabel = uilabel(app.UIFigure);
            app.DistanciaLabel.Text = 'Distancia';
            app.DistanciacmLabel = uilabel(app.UIFigure);
            app.DistanciacmLabel.Text = '1cm';
            app.AnguloLabel = uilabel(app.UIFigure);
            app.AnguloLabel.Text = 'Angulo';
            app.AngulogrdsLabel = uilabel(app.UIFigure);
            app.AngulogrdsLabel.Text = '0.01 °';
            app.PositionMotor = uigauge(app.UIFigure, 'circular');
            app.PositionMotor.ScaleDirection = 'counterclockwise';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SOLINDAR

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end